classdef FourierSeriesExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierSeriesExplorerUIFigure   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportwaveformMenu              matlab.ui.container.Menu
        WaveformeditorMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        FourierseriespropertiesexplainedMenu  matlab.ui.container.Menu
        TipMenu                         matlab.ui.container.Menu
        ContactthetutorMenu             matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisSynthesisTab            matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ControlPanel                    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        InputsPanel                     matlab.ui.container.Panel
        ComplexcoefficientsCheckBox     matlab.ui.control.CheckBox
        WaveformperiodsSliderLabel      matlab.ui.control.Label
        WaveformperiodsSlider           matlab.ui.control.Slider
        NumberofharmonicsNSpinnerLabel  matlab.ui.control.Label
        NumberofharmonicsSpinner        matlab.ui.control.Spinner
        ExamineButtonGroup              matlab.ui.container.ButtonGroup
        PlotCoefficients                matlab.ui.control.RadioButton
        PlotHarmonics                   matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        AnalysisFormula                 matlab.ui.control.Image
        SynthesisFormula                matlab.ui.control.Image
        AnalysisLabel                   matlab.ui.control.Label
        SynthesisLabel                  matlab.ui.control.Label
        SynthesisRMSErrorEditFieldLabel  matlab.ui.control.Label
        SynthesisRMSErrorEditField      matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        WaveformAxes                    matlab.ui.control.UIAxes
        CoefficientsAxes                matlab.ui.control.UIAxes
        PropertiesTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        PropertyDropDownLabel           matlab.ui.control.Label
        PropertyDropDown                matlab.ui.control.DropDown
        TimeDomainEquation              matlab.ui.control.Image
        CoefficientEquation             matlab.ui.control.Image
        ExamineButtonGroup_2            matlab.ui.container.ButtonGroup
        TimeDomain                      matlab.ui.control.RadioButton
        FourierCoefficients             matlab.ui.control.RadioButton
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        t0EditFieldLabel                matlab.ui.control.Label
        t0EditField                     matlab.ui.control.NumericEditField
        FunctionxButtonGroup            matlab.ui.container.ButtonGroup
        ComplexvaluedButton             matlab.ui.control.RadioButton
        RealvaluedButton                matlab.ui.control.RadioButton
        ThreeFigures                    matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        xFunAxes                        matlab.ui.control.UIAxes
        yFunAxes                        matlab.ui.control.UIAxes
        zFunAxes                        matlab.ui.control.UIAxes
        TwoFigures                      matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        xFunAxes2                       matlab.ui.control.UIAxes
        zFunAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Version string (Major.Minor)
        Version = "1.0"
        % Imported waveform to be used in Analysis & Synthesis tab
        ImportedWaveform = []
        % Import waveform app handle
        ImportWaveformApp
    end
    
    properties (Access = private, Constant)
        %MaxTime  Time span for plots
        MaxTime = 20            % seconds
        %SamplingPeriod Sampling period
        %   Even though Fourier series operates on continuous signals,
        %   computers have to work with sampled signals. Choose a sampling
        %   period small enough for the waveform periods examined in this
        %   app.
        SamplingPeriod = 1e-4   % seconds
    end
    
    properties (Access = private, Dependent)
        %MaxNumHarmonics Maximum number of harmonics
        %   Define maximum number of harmonics to support. This depends on
        %   the sampling period.
        MaxNumHarmonics
    end
    
    methods (Access = private)
        function run(app, T, N)
            Ts = app.SamplingPeriod;
            
            % Periodic waveform
            t = 0:Ts:app.MaxTime;
            f = getFunctionValues(app,t,T);
            plot(app.WaveformAxes, t, f)
            
            if app.ComplexcoefficientsCheckBox.Value
                c = analyzeComplex(app, T, N);
                
                if app.PlotCoefficients.Value
                    stem(app.CoefficientsAxes, -N:N, real(c))
                    hold(app.CoefficientsAxes, "on")
                    stem(app.CoefficientsAxes, -N:N, imag(c))
                    hold(app.CoefficientsAxes, "off")
                    legend(app.CoefficientsAxes, 'real(c)', 'imag(c)')
                else
                    plotHarmonicsComplex(app, T, N, c)
                end
                
                [f_s, t] = synthesizeComplex(app, T, N, c);
                f_s = real(f_s); % remove the small complex part due
                % to numerical errors
            else
                [a,b] = analyzeReal(app, T, N);
                
                if app.PlotCoefficients.Value
                    stem(app.CoefficientsAxes, 0:N, a)
                    hold(app.CoefficientsAxes, "on")
                    stem(app.CoefficientsAxes, 0:N, b)
                    hold(app.CoefficientsAxes, "off")
                    legend(app.CoefficientsAxes, 'a_n', 'b_n')
                else
                    plotHarmonicsReal(app, T, N, a, b)
                end
                
                [f_s, t] = synthesizeReal(app, T, N, a, b);
            end
            hold(app.WaveformAxes,"on")
            plot(app.WaveformAxes,t,f_s)
            hold(app.WaveformAxes,"off")
            legend(app.WaveformAxes, 'f(t)', 'f_s(t)')
            
            rmsError = mean(abs(f - f_s).^2);
            app.SynthesisRMSErrorEditField.Value = rmsError;
        end
        
        function c = analyzeComplex(app, T, N)
            %analyzeComplex Fourier analysis with complex valued coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:T-Ts;
            f = getFunctionValues(app,t,T);
            
            idx = 1;
            c = zeros(1,2*N+1);
            for n = -N:N
                expTerm = exp(-1i*2*pi*t*n/T);
                c(idx) = (Ts/T)*sum(f .* expTerm);
                idx = idx + 1;
            end
        end
        
        function [a,b] = analyzeReal(app, T, N)
            %analyzeReal Fourier analysis with real valued coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:T-Ts;
            f = getFunctionValues(app,t,T);
            
            idx = 1;                    % Index for a and b vectors
            a = zeros(1,N+1);           % Preallocate for speed
            b = zeros(1,N+1);           % Preallocate for speed
            for n = 0:N
                cosTerm = cos(2*pi*t*n/T);
                sinTerm = sin(2*pi*t*n/T);
                a(idx) = (2*Ts/T)*sum(f .* cosTerm);    % Integrate from t = 0 to T
                b(idx) = (2*Ts/T)*sum(f .* sinTerm);    % Integrate from t = 0 to T
                idx = idx + 1;
            end
        end
        
        function [f_s,t] = synthesizeComplex(app, T, N, c)
            %synthesizeComplex Fourier synthesis with complex valued
            %coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            f_s = zeros(size(t));
            idx = 1;
            for n=-N:N
                expTerm = exp(1i*2*pi*t*n/T);
                f_s = f_s  + c(idx)*expTerm;
                idx = idx + 1;
            end
        end
        
        function [f_s,t] = synthesizeReal(app, T, N, a, b)
            %synthesizeReal Fourier synthesis with complex valued
            %coefficients
            
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            f_s = (a(1)/2)*ones(size(t));
            for n=1:N
                cosTerm = cos(2*pi*t*n/T);
                sinTerm = sin(2*pi*t*n/T);
                % Accumulate f_s with the nth term. Since n starts from 0,
                % we use index n+1 in MATLAB, which uses 1-based indexing.
                f_s = f_s  + a(n +1)*cosTerm + b(n +1)*sinTerm;
            end
        end
        
        function f = getFunctionValues(app,t,T)
            f = zeros(size(t));
            if isempty(app.ImportedWaveform)
                A = 2;
                f(t < (T/2)) = A;           % Set f(t) to A for t values less than T/2.
                f((t>=(T/2)) & (t<T)) = -A; % Set f(t) to -A for t values greater than T/2 and less than T
                
                % Check if t has values greater than T
                if any(t > T)
                    f(mod(t,T) < (T/2)) = A; % Set f(t) to A for t values less than T/2.
                    f(mod(t,T)>=(T/2)) = -A; % Set f(t) to -A for t values greater than T/2 and less than T
                end
            else
                waveformInfo = app.ImportedWaveform;
                TsOrig = waveformInfo.Period / length(waveformInfo.Amplitude);
                tOrig = 0:TsOrig:waveformInfo.Period-TsOrig;
                Ts = app.SamplingPeriod;
                delta_t = TsOrig*(waveformInfo.Period/T)*(TsOrig/Ts);
                t1Period = 0:delta_t:waveformInfo.Period-delta_t;
                f1Period = interp1(tOrig,waveformInfo.Amplitude,t1Period,'linear','extrap');
                f = f1Period(mod(floor(t/Ts),T/Ts) +1);
            end
        end
        
        function plotHarmonicsComplex(app, T, N, c)
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            
            legendStr = cell(1,2*N+1);
            idx = 1;
            for n=-N:N
                expTerm = exp(1i*2*pi*t*n/T);
                harmonic = c(idx)*expTerm;
                plot(app.CoefficientsAxes, t, real(harmonic))
                hold(app.CoefficientsAxes, "on")
                plot(app.CoefficientsAxes, t, imag(harmonic), '--')
                legendStr{(idx-1)*2+1} = sprintf("n=%d - real", n);
                legendStr{(idx-1)*2+2} = sprintf("n=%d - imag", n);
                idx = idx + 1;
            end
            hold(app.CoefficientsAxes, "off")
            legend(app.CoefficientsAxes, legendStr{:})
        end
        
        function plotHarmonicsReal(app, T, N, a, b)
            Ts = app.SamplingPeriod;
            t = 0:Ts:app.MaxTime;
            harmonic = (a(1)/2)*ones(size(t));
            plot(app.CoefficientsAxes, t, harmonic)
            hold(app.CoefficientsAxes, "on")
            legendStr = cell(1,N+1);
            legendStr{1} = "n=0";
            for n=1:N
                cosTerm = cos(2*pi*t*n/T);
                sinTerm = sin(2*pi*t*n/T);
                harmonic = a(n +1)*cosTerm + b(n +1)*sinTerm;
                plot(app.CoefficientsAxes, t, harmonic)
                legendStr{n +1} = sprintf("n=%d", n);
            end
            hold(app.CoefficientsAxes, "off")
            legend(app.CoefficientsAxes, legendStr{:})
        end
        
        function checkMaxNumHarmonics(app)
            if app.ComplexcoefficientsCheckBox.Value
                Nmax = 3;
            else
                Nmax = 10;
            end
            if app.NumberofharmonicsSpinner.Value > Nmax
                app.NumberofharmonicsSpinner.Value = Nmax;
                uialert(app.FourierSeriesExplorerUIFigure, ...
                    sprintf("Maximum number of harmonics supported for " ...
                    + "plotting is %d. Number of harmonics is set to %d.", ...
                    Nmax, Nmax), ...
                    "Fourier Series Explorer", "Icon","warning")
            end
            app.NumberofharmonicsSpinner.Limits = [0 Nmax];
        end
        
        function updatePropertiesTab(app)
            switch app.PropertyDropDown.Value
                case 'Linearity'
                    updateLinearity(app)
                case 'Time reversal'
                    updateTimeReversal(app)
                case 'Time scaling'
                    updateTimeScaling(app)
                case 'Time shift'
                    updateTimeShift(app)
                case 'Convolution in time'
                    updateConvolutionInTime(app)
                case 'Convolution in frequency'
                    updateConvolutionInFrequency(app)
                case 'Conjugate & Symmetry'
                    updateConjugateSymmetry(app)
            end
            
        end
        
        function updateConjugateSymmetry(app)
            app.TimeDomainEquation.ImageSource = 'conjsym_time.PNG';
            app.CoefficientEquation.ImageSource = 'conjsym_frequency.PNG';
            app.aEditField.Visible = "off";
            app.aEditFieldLabel.Visible = "off";
            app.bEditField.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            app.t0EditField.Visible = "off";
            app.t0EditFieldLabel.Visible = "off";
            app.FunctionxButtonGroup.Visible = "on";
            app.ThreeFigures.Visible = "off";
            app.TwoFigures.Visible = "on";
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [x,t] = xValues(app);
                if app.FunctionxButtonGroup.SelectedObject == app.ComplexvaluedButton
                    y = yValues(app);
                    x = x + 1i*y;
                end
                plot(app.xFunAxes2,t,real(x))
                hold(app.xFunAxes2,"on")
                plot(app.xFunAxes2,t,imag(x))
                hold(app.xFunAxes2, "off")
                title(app.xFunAxes2,"Function x(t)")
                ylabel(app.xFunAxes2,"x(t)")
                xlabel(app.xFunAxes2,"Time (s)")
                legend(app.xFunAxes2,'real','imag')
                
                z = conj(x);
                plot(app.zFunAxes2,t,real(z))
                hold(app.zFunAxes2,"on")
                plot(app.zFunAxes2,t,imag(z))
                hold(app.zFunAxes2, "off")
                title(app.zFunAxes2,"Function z(t)")
                ylabel(app.zFunAxes2,"z(t)")
                xlabel(app.zFunAxes2,"Time (s)")
                legend(app.zFunAxes2,'real','imag')
            else
                [x,t] = xValues(app);
                if app.FunctionxButtonGroup.SelectedObject == app.ComplexvaluedButton
                    y = yValues(app);
                    x = x + 1i*y;
                end
                N = 10;
                x_n = analysis(app,x,t,N);
                
                stem(app.xFunAxes2,-N:N,real(x_n))
                hold(app.xFunAxes2, "on")
                stem(app.xFunAxes2,-N:N,imag(x_n))
                hold(app.xFunAxes2, "off")
                title(app.xFunAxes2,"Coefficients x_n")
                ylabel(app.xFunAxes2,"Amplitude")
                xlabel(app.xFunAxes2,"Coeffiicent index (n)")
                legend(app.xFunAxes2,'real','imag')
                
                z = conj(x);
                z_n = analysis(app,z,t,N);
                stem(app.zFunAxes2,-N:N,real(z_n))
                hold(app.zFunAxes2, "on")
                stem(app.zFunAxes2,-N:N,imag(z_n))
                hold(app.zFunAxes2, "off")
                title(app.zFunAxes2,"Coefficients z_n")
                ylabel(app.zFunAxes2,"Amplitude")
                xlabel(app.zFunAxes2,"Coeffiicent index (n)")
                legend(app.zFunAxes2,'real','imag')
            end
        end
        
        function updateConvolutionInFrequency(app)
            app.TimeDomainEquation.ImageSource = 'convfreq_time.PNG';
            app.CoefficientEquation.ImageSource = 'convfreq_frequency.PNG';
            app.aEditField.Visible = "off";
            app.bEditField.Visible = "off";
            app.aEditFieldLabel.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            app.t0EditField.Visible = "off";
            app.t0EditFieldLabel.Visible = "off";
            app.FunctionxButtonGroup.Visible = "off";
            app.ThreeFigures.Visible = "on";
            app.TwoFigures.Visible = "off";
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [x,t] = xValues(app);
                plot(app.xFunAxes,t,x)
                title(app.xFunAxes,"Function x(t)")
                ylabel(app.xFunAxes,"x(t)")
                xlabel(app.xFunAxes,"Time (s)")
                
                y = yValues(app);
                plot(app.yFunAxes,t,y)
                title(app.yFunAxes,"Function y(t)")
                ylabel(app.yFunAxes,"y(t)")
                xlabel(app.yFunAxes,"Time (s)")
                
                z = x.*y;
                plot(app.zFunAxes,t,real(z))
                hold(app.zFunAxes, "on")
                plot(app.zFunAxes,t,imag(z))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Function z(t)")
                ylabel(app.zFunAxes,"z(t)")
                xlabel(app.zFunAxes,"Time (s)")
                legend(app.zFunAxes,'real','imag')
            else
                [x,t] = xValues(app);
                N = 10;
                x_n = analysis(app,x,t,N);
                
                stem(app.xFunAxes,-N:N,real(x_n))
                hold(app.xFunAxes, "on")
                stem(app.xFunAxes,-N:N,imag(x_n))
                hold(app.xFunAxes, "off")
                title(app.xFunAxes,"Coefficients x_n")
                ylabel(app.xFunAxes,"Amplitude")
                xlabel(app.xFunAxes,"Coeffiicent index (n)")
                legend(app.xFunAxes,'real','imag')
                
                y = yValues(app);
                y_n = analysis(app,y,t,N);
                stem(app.yFunAxes,-N:N,real(y_n))
                hold(app.yFunAxes, "on")
                stem(app.yFunAxes,-N:N,imag(y_n))
                hold(app.yFunAxes, "off")
                title(app.yFunAxes,"Coefficients y_n")
                ylabel(app.yFunAxes,"Amplitude")
                xlabel(app.yFunAxes,"Coeffiicent index (n)")
                legend(app.yFunAxes,'real','imag')
                
                z = x.*y;
                z_n = analysis(app,z,t,N);
                stem(app.zFunAxes,-N:N,real(z_n))
                hold(app.zFunAxes, "on")
                stem(app.zFunAxes,-N:N,imag(z_n))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Coefficients z_n")
                ylabel(app.zFunAxes,"Amplitude")
                xlabel(app.zFunAxes,"Coeffiicent index (n)")
                legend(app.zFunAxes,'real','imag')
            end
        end
        
        
        function updateConvolutionInTime(app)
            app.TimeDomainEquation.ImageSource = 'convtime_time.PNG';
            app.CoefficientEquation.ImageSource = 'convtime_frequency.PNG';
            app.aEditField.Visible = "off";
            app.bEditField.Visible = "off";
            app.aEditFieldLabel.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            app.t0EditField.Visible = "off";
            app.t0EditFieldLabel.Visible = "off";
            app.FunctionxButtonGroup.Visible = "off";
            app.ThreeFigures.Visible = "on";
            app.TwoFigures.Visible = "off";
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [x,t] = xValues(app);
                plot(app.xFunAxes,t,x)
                title(app.xFunAxes,"Function x(t)")
                ylabel(app.xFunAxes,"x(t)")
                xlabel(app.xFunAxes,"Time (s)")
                
                y = yValues(app);
                plot(app.yFunAxes,t,y)
                title(app.yFunAxes,"Function y(t)")
                ylabel(app.yFunAxes,"y(t)")
                xlabel(app.yFunAxes,"Time (s)")
                
                z = circularConvolution(app,x,y);
                plot(app.zFunAxes,t,real(z))
                hold(app.zFunAxes, "on")
                plot(app.zFunAxes,t,imag(z))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Function z(t)")
                ylabel(app.zFunAxes,"z(t)")
                xlabel(app.zFunAxes,"Time (s)")
                legend(app.zFunAxes,'real','imag')
            else
                [x,t] = xValues(app);
                N = 10;
                x_n = analysis(app,x,t,N);
                
                stem(app.xFunAxes,-N:N,real(x_n))
                hold(app.xFunAxes, "on")
                stem(app.xFunAxes,-N:N,imag(x_n))
                hold(app.xFunAxes, "off")
                title(app.xFunAxes,"Coefficients x_n")
                ylabel(app.xFunAxes,"Amplitude")
                xlabel(app.xFunAxes,"Coeffiicent index (n)")
                legend(app.xFunAxes,'real','imag')
                
                y = yValues(app);
                y_n = analysis(app,y,t,N);
                stem(app.yFunAxes,-N:N,real(y_n))
                hold(app.yFunAxes, "on")
                stem(app.yFunAxes,-N:N,imag(y_n))
                hold(app.yFunAxes, "off")
                title(app.yFunAxes,"Coefficients y_n")
                ylabel(app.yFunAxes,"Amplitude")
                xlabel(app.yFunAxes,"Coeffiicent index (n)")
                legend(app.yFunAxes,'real','imag')
                
                z = circularConvolution(app,x,y);
                z_n = analysis(app,z,t,N);
                stem(app.zFunAxes,-N:N,real(z_n))
                hold(app.zFunAxes, "on")
                stem(app.zFunAxes,-N:N,imag(z_n))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Coefficients z_n")
                ylabel(app.zFunAxes,"Amplitude")
                xlabel(app.zFunAxes,"Coeffiicent index (n)")
                legend(app.zFunAxes,'real','imag')
            end
        end
        
        function updateTimeShift(app)
            app.TimeDomainEquation.ImageSource = 'timeshift_time.PNG';
            app.CoefficientEquation.ImageSource = 'timeshift_frequency.PNG';
            app.aEditField.Visible = "off";
            app.aEditFieldLabel.Visible = "off";
            app.bEditField.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            app.t0EditField.Visible = "on";
            app.t0EditFieldLabel.Visible = "on";
            app.FunctionxButtonGroup.Visible = "off";
            app.ThreeFigures.Visible = "off";
            app.TwoFigures.Visible = "on";
            
            t0 = app.t0EditField.Value;
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [x,t] = xValues(app);
                plot(app.xFunAxes2,t,x)
                title(app.xFunAxes2,"Function x(t)")
                ylabel(app.xFunAxes2,"x(t)")
                xlabel(app.xFunAxes2,"Time (s)")
                
                t=(0:Ts:T-Ts)+t0;
                z = xFun(app, t-t0);
                plot(app.zFunAxes2,t,z)
                title(app.zFunAxes2,"Function z(t)")
                ylabel(app.zFunAxes2,"z(t)")
                xlabel(app.zFunAxes2,"Time (s)")
            else
                [x,t] = xValues(app);
                N = 10;
                x_n = analysis(app,x,t,N);
                
                stem(app.xFunAxes2,-N:N,real(x_n))
                hold(app.xFunAxes2, "on")
                stem(app.xFunAxes2,-N:N,imag(x_n))
                hold(app.xFunAxes2, "off")
                title(app.xFunAxes2,"Coefficients x_n")
                ylabel(app.xFunAxes2,"Amplitude")
                xlabel(app.xFunAxes2,"Coeffiicent index (n)")
                legend(app.xFunAxes2,'real','imag')
                
                t=(0:Ts:T-Ts)+t0;
                z = xFun(app, t-t0);
                z_n = analysis(app,z,t,N);
                stem(app.zFunAxes2,-N:N,real(z_n))
                hold(app.zFunAxes2, "on")
                stem(app.zFunAxes2,-N:N,imag(z_n))
                hold(app.zFunAxes2, "off")
                title(app.zFunAxes2,"Coefficients z_n")
                ylabel(app.zFunAxes2,"Amplitude")
                xlabel(app.zFunAxes2,"Coeffiicent index (n)")
                legend(app.zFunAxes2,'real','imag')
            end
        end
        
        function updateTimeScaling(app)
            app.TimeDomainEquation.ImageSource = 'timesscaling_time.PNG';
            app.CoefficientEquation.ImageSource = 'timescaling_frequency.PNG';
            app.aEditField.Visible = "on";
            app.aEditFieldLabel.Visible = "on";
            app.bEditField.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            app.t0EditField.Visible = "off";
            app.t0EditFieldLabel.Visible = "off";
            app.FunctionxButtonGroup.Visible = "off";
            app.ThreeFigures.Visible = "off";
            app.TwoFigures.Visible = "on";
            
            a = str2double(app.aEditField.Value);
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [x,t] = xValues(app);
                plot(app.xFunAxes2,t,x)
                title(app.xFunAxes2,"Function x(t)")
                ylabel(app.xFunAxes2,"x(t)")
                xlabel(app.xFunAxes2,"Time (s)")
                
                z = xFun(app,a*t);
                plot(app.zFunAxes2,t,z)
                title(app.zFunAxes2,"Function z(t)")
                ylabel(app.zFunAxes2,"z(t)")
                xlabel(app.zFunAxes2,"Time (s)")
            else
                [x,t] = xValues(app);
                N = 10;
                x_n = analysis(app,x,t,N);
                
                stem(app.xFunAxes2,-N:N,real(x_n))
                hold(app.xFunAxes2, "on")
                stem(app.xFunAxes2,-N:N,imag(x_n))
                hold(app.xFunAxes2, "off")
                title(app.xFunAxes2,"Coefficients x_n")
                ylabel(app.xFunAxes2,"Amplitude")
                xlabel(app.xFunAxes2,"Coeffiicent index (n)")
                legend(app.xFunAxes2,'real','imag')
                
                z = xFun(app,a*t);
                T = app.WaveformperiodsSlider.Value;
                app.WaveformperiodsSlider.Value = T/a;
                z_n = analysis(app,z,t,N);
                app.WaveformperiodsSlider.Value = T;
                stem(app.zFunAxes2,-N:N,real(z_n))
                hold(app.zFunAxes2, "on")
                stem(app.zFunAxes2,-N:N,imag(z_n))
                hold(app.zFunAxes2, "off")
                title(app.zFunAxes2,"Coefficients z_n")
                ylabel(app.zFunAxes2,"Amplitude")
                xlabel(app.zFunAxes2,"Coeffiicent index (n)")
                legend(app.zFunAxes2,'real','imag')
            end
        end
        
        function updateTimeReversal(app)
            app.TimeDomainEquation.ImageSource = 'timerevelsal_time.PNG';
            app.CoefficientEquation.ImageSource = 'timerevelsal_frequency.PNG';
            app.aEditField.Visible = "off";
            app.bEditField.Visible = "off";
            app.aEditFieldLabel.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            app.t0EditField.Visible = "off";
            app.t0EditFieldLabel.Visible = "off";
            app.FunctionxButtonGroup.Visible = "off";
            app.ThreeFigures.Visible = "off";
            app.TwoFigures.Visible = "on";
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [y,t] = yValues(app);
                plot(app.xFunAxes2,t,y)
                title(app.xFunAxes2,"Function x(t)")
                ylabel(app.xFunAxes2,"x(t)")
                xlabel(app.xFunAxes2,"Time (s)")
                
                z = fliplr(y);
                plot(app.zFunAxes2,t,z)
                title(app.zFunAxes2,"Function z(t)")
                ylabel(app.zFunAxes2,"z(t)")
                xlabel(app.zFunAxes2,"Time (s)")
            else
                [y,t] = yValues(app);
                N = 10;
                y_n = analysis(app,y,t,N);
                
                stem(app.xFunAxes2,-N:N,real(y_n))
                hold(app.xFunAxes2, "on")
                stem(app.xFunAxes2,-N:N,imag(y_n))
                hold(app.xFunAxes2, "off")
                title(app.xFunAxes2,"Coefficients x_n")
                ylabel(app.xFunAxes2,"Amplitude")
                xlabel(app.xFunAxes2,"Coeffiicent index (n)")
                legend(app.xFunAxes2,'real','imag')
                
                z = fliplr(y);
                z_n = analysis(app,z,t,N);
                stem(app.zFunAxes2,-N:N,real(z_n))
                hold(app.zFunAxes2, "on")
                stem(app.zFunAxes2,-N:N,imag(z_n))
                hold(app.zFunAxes2, "off")
                title(app.zFunAxes2,"Coefficients z_n")
                ylabel(app.zFunAxes2,"Amplitude")
                xlabel(app.zFunAxes2,"Coeffiicent index (n)")
                legend(app.zFunAxes2,'real','imag')
            end
        end
        
        function updateLinearity(app)
            app.TimeDomainEquation.ImageSource = 'linearity_time.PNG';
            app.CoefficientEquation.ImageSource = 'linearity_frequency.PNG';
            app.aEditField.Visible = "on";
            app.bEditField.Visible = "on";
            app.aEditFieldLabel.Visible = "on";
            app.bEditFieldLabel.Visible = "on";
            app.t0EditField.Visible = "off";
            app.t0EditFieldLabel.Visible = "off";
            app.FunctionxButtonGroup.Visible = "off";
            app.ThreeFigures.Visible = "on";
            app.TwoFigures.Visible = "off";
            
            if app.ExamineButtonGroup_2.SelectedObject == app.TimeDomain
                [x,t] = xValues(app);
                plot(app.xFunAxes,t,x)
                title(app.xFunAxes,"Function x(t)")
                ylabel(app.xFunAxes,"x(t)")
                xlabel(app.xFunAxes,"Time (s)")
                
                y = yValues(app);
                plot(app.yFunAxes,t,y)
                title(app.yFunAxes,"Function y(t)")
                ylabel(app.yFunAxes,"y(t)")
                xlabel(app.yFunAxes,"Time (s)")
                
                z = str2double(app.aEditField.Value) * x + app.bEditField.Value * y;
                plot(app.zFunAxes,t,real(z))
                hold(app.zFunAxes, "on")
                plot(app.zFunAxes,t,imag(z))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Function z(t)")
                ylabel(app.zFunAxes,"z(t)")
                xlabel(app.zFunAxes,"Time (s)")
                legend(app.zFunAxes,'real','imag')
            else
                [x,t] = xValues(app);
                N = 10;
                x_n = analysis(app,x,t,N);
                
                stem(app.xFunAxes,-N:N,real(x_n))
                hold(app.xFunAxes, "on")
                stem(app.xFunAxes,-N:N,imag(x_n))
                hold(app.xFunAxes, "off")
                title(app.xFunAxes,"Coefficients x_n")
                ylabel(app.xFunAxes,"Amplitude")
                xlabel(app.xFunAxes,"Coeffiicent index (n)")
                legend(app.xFunAxes,'real','imag')
                
                y = yValues(app);
                y_n = analysis(app,y,t,N);
                stem(app.yFunAxes,-N:N,real(y_n))
                hold(app.yFunAxes, "on")
                stem(app.yFunAxes,-N:N,imag(y_n))
                hold(app.yFunAxes, "off")
                title(app.yFunAxes,"Coefficients y_n")
                ylabel(app.yFunAxes,"Amplitude")
                xlabel(app.yFunAxes,"Coeffiicent index (n)")
                legend(app.yFunAxes,'real','imag')
                
                z = str2double(app.aEditField.Value) * x + app.bEditField.Value * y;
                z_n = analysis(app,z,t,N);
                stem(app.zFunAxes,-N:N,real(z_n))
                hold(app.zFunAxes, "on")
                stem(app.zFunAxes,-N:N,imag(z_n))
                hold(app.zFunAxes, "off")
                title(app.zFunAxes,"Coefficients z_n")
                ylabel(app.zFunAxes,"Amplitude")
                xlabel(app.zFunAxes,"Coeffiicent index (n)")
                legend(app.zFunAxes,'real','imag')
            end
        end
        
        function [x,t] = xValues2(app)
            %xValues  Values of function x for one period
            %  This is a time symmetric function
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            t = 0:Ts:T-Ts;
            x = zeros(size(t));
            
            % x = 2*t for 0<t<T/2
            idx = t < T/2;
            x(idx) = 2*t(idx);
            
            % x = 2*T-2*t for T/2<t<T
            idx = (t >= T/2) & (t < T);
            x(idx) = 2*T - 2*t(idx);
        end
        
        function [x,t] = xValues(app)
            %xValues  Values of function x for one period
            
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            t = 0:Ts:T-Ts;
            x = zeros(size(t));
            
            % x = 2*t for 0<t<T/3
            idx = t < T/3;
            x(idx) = 2*t(idx);
            
            % x = T-t for T/3<t<T
            idx = (t >= T/3) & (t < T);
            x(idx) = T - t(idx);
        end
        
        function [y,t] = yValues(app)
            %xValues  Values of function y for one period
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            t = 0:Ts:T-Ts;
            y = zeros(size(t));
            
            % y = 2*t for 0<t<T/4
            idx = t < T/4;
            y(idx) = 2*t(idx);
            
            % y = T for T/4<t<T/2
            idx = (t >= T/4) & (t < T/2);
            y(idx) = T/2;
            
            % y = T + exp(-3(t+T/2)) for T/2<t<3T/4
            idx = (t >= T/2) & (t < 3*T/4);
            y(idx) = T/2 - 1 + exp(-3*(t(idx)-T/2));
            
            % y = T + exp(-(t+T/2)) for 3T/4<t<T
            idx = (t >= 3*T/4) & (t < T);
            y(idx) = 0;
        end
        
        function c = analysis(app,f,t,N)
            %analysis Fourier analysis
            
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            
            idx = 1;
            c = zeros(1,2*N+1);
            for n = -N:N
                expTerm = exp(-1i*2*pi*t*n/T);
                c(idx) = (Ts/T)*sum(f .* expTerm);
                idx = idx + 1;
            end
        end
        
        function xq = xFun(app,t)
            %xFun    Evaluate x function for t values
            
            T = app.WaveformperiodsSlider.Value;
            
            % Get x values for one period
            [x,torig] = xValues(app);
            
            % Find values of x for the given t where t < T (1 period)
            tq = t(t<T);
            xq1T = interp1(torig, x, tq);
            
            % Replicate to match the whole t range
            xqTemp = repmat(xq1T, 1, ceil(length(t) / length(xq1T)));
            xq = xqTemp(1:length(t));
        end
        
        
        function z = circularConvolution(app,x,y)
            %circularConvolution Circular convolution
            Ts = app.SamplingPeriod;
            T = app.WaveformperiodsSlider.Value;
            
            z = (Ts/T)*ifft(fft(x).*fft(y));
        end
    end
    
    methods (Access = public)
        
        function importWaveform(app, x, T)
            app.WaveformperiodsSlider.Value = T;
            waveformInfo = struct('Amplitude', x, ...
                'Period', T);
            app.ImportedWaveform = waveformInfo;
            N = floor(app.NumberofharmonicsSpinner.Value);
            run(app, T, N);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Analysis - Synthesis Tab
            T = app.WaveformperiodsSlider.Value;
            N = floor(app.NumberofharmonicsSpinner.Value);
            run(app, T, N);
            
            % Properties Tab
            updatePropertiesTab(app)
        end

        % Value changing function: WaveformperiodsSlider
        function WaveformperiodsSliderValueChanging(app, event)
            T = event.Value;
            Ts = app.SamplingPeriod;
            
            % Make sure that T/Ts is integer
            T = floor(T/Ts)*Ts;
            
            Nmax = floor(T / (2*Ts));
            N = floor(app.NumberofharmonicsSpinner.Value);
            if N > Nmax
                app.NumberofharmonicsSpinner.Value = Nmax;
                N = Nmax;
            end
            app.NumberofharmonicsSpinner.Limits = [0 Nmax];
            run(app, T, N);
        end

        % Value changing function: NumberofharmonicsSpinner
        function NumberofharmonicsSpinnerValueChanging(app, event)
            T = app.WaveformperiodsSlider.Value;
            N = floor(event.Value);
            run(app, T, N);
        end

        % Value changed function: ComplexcoefficientsCheckBox
        function ComplexcoefficientsCheckBoxValueChanged(app, event)
            value = app.ComplexcoefficientsCheckBox.Value;
            if value
                app.AnalysisFormula.ImageSource = ...
                    "analysis_complex_coeffs.PNG";
                app.SynthesisFormula.ImageSource = ...
                    "synthesis_complex_coeffs.PNG";
            else
                app.AnalysisFormula.ImageSource = ...
                    "analysis_real_coeffs.PNG";
                app.SynthesisFormula.ImageSource = ...
                    "synthesis_real_coeffs.PNG";
            end
            T = app.WaveformperiodsSlider.Value;
            if app.ExamineButtonGroup.SelectedObject == app.PlotHarmonics
                checkMaxNumHarmonics(app)
            end
            N = floor(app.NumberofharmonicsSpinner.Value);
            run(app, T, N);
        end

        % Selection changed function: ExamineButtonGroup
        function ExamineButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExamineButtonGroup.SelectedObject;
            if selectedButton == app.PlotHarmonics
                checkMaxNumHarmonics(app)
                title(app.CoefficientsAxes, "Harmonics")
                xlabel(app.CoefficientsAxes, "t (s)")
            else
                title(app.CoefficientsAxes, "Fourier Series Coefficients")
                xlabel(app.CoefficientsAxes, "Coefficient Index (n)")
                T = app.WaveformperiodsSlider.Value;
                Ts = app.SamplingPeriod;
                Nmax = floor(T / (2*Ts));
                N = floor(app.NumberofharmonicsSpinner.Value);
                if N > Nmax
                    app.NumberofharmonicsSpinner.Value = Nmax;
                end
                app.NumberofharmonicsSpinner.Limits = [0 Nmax];
            end
            T = app.WaveformperiodsSlider.Value;
            N = app.NumberofharmonicsSpinner.Value;
            run(app, T, N)
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            updateLinearity(app)
        end

        % Selection changed function: ExamineButtonGroup_2
        function ExamineButtonGroup_2SelectionChanged(app, event)
            updatePropertiesTab(app)
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            updatePropertiesTab(app)
        end

        % Value changed function: PropertyDropDown
        function PropertyDropDownValueChanged(app, event)
            updatePropertiesTab(app)
        end

        % Value changed function: t0EditField
        function t0EditFieldValueChanged(app, event)
            updatePropertiesTab(app)
        end

        % Menu selected function: 
        % FourierseriespropertiesexplainedMenu
        function FourierseriespropertiesexplainedMenuSelected(app, event)
            open('fourier_series_properties.mlx')
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msg = "Fourier Series Properties App, version " + app.Version + newline + newline ...
                + "Explore the Fourier series properties visually" + newline ...
                + "Companion to Fourier Series Properties Live Script" + newline ...
                + newline ...
                + "Copyright 2020, Angora Tutor";
            uialert(app.FourierSeriesExplorerUIFigure, ...
                msg, 'Fourier series properties', 'Icon',"angora_goat_elips.png")
        end

        % Menu selected function: ContactthetutorMenu
        function ContactthetutorMenuSelected(app, event)
            web("https://angoratutor.com/contact")
        end

        % Menu selected function: ImportwaveformMenu
        function ImportwaveformMenuSelected(app, event)
            app.ImportWaveformApp = ImportWaveformFSE(app);
        end

        % Menu selected function: WaveformeditorMenu
        function WaveformeditorMenuSelected(app, event)
            uialert(app.FourierSeriesExplorerUIFigure, ...
                "Coming soon!", "Fourier series properties", ...
                "Icon", "info")
        end

        % Selection changed function: FunctionxButtonGroup
        function FunctionxButtonGroupSelectionChanged(app, event)
            updatePropertiesTab(app)
        end

        % Menu selected function: TipMenu
        function TipMenuSelected(app, event)
            tips = ["Fourier series coefficients of a real-valued signal is conjugate symmetric.", ...
                "Convolution in time domain is multiplication in frequency domain."];
            
            idx = randi(length(tips));
            uialert(app.FourierSeriesExplorerUIFigure, ...
                tips(idx), "Fourier series properties", ...
                "Icon", "info")
        end

        % Value changed function: WaveformperiodsSlider
        function WaveformperiodsSliderValueChanged(app, event)
            T = app.WaveformperiodsSlider.Value;
            Ts = app.SamplingPeriod;
            % Make sure that T/Ts is integer
            T = floor(T/Ts)*Ts;
            app.WaveformperiodsSlider.Value = T;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierSeriesExplorerUIFigure = uifigure('Visible', 'off');
            app.FourierSeriesExplorerUIFigure.Name = 'Fourier Series Explorer';
            app.FourierSeriesExplorerUIFigure.Tag = 'FourierSeriesExplorer';
            app.FileMenu = uimenu(app.FourierSeriesExplorerUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportwaveformMenu = uimenu(app.FileMenu);
            app.ImportwaveformMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportwaveformMenuSelected, true);
            app.ImportwaveformMenu.Text = 'Import waveform...';
            app.WaveformeditorMenu = uimenu(app.FileMenu);
            app.WaveformeditorMenu.MenuSelectedFcn = createCallbackFcn(app, @WaveformeditorMenuSelected, true);
            app.WaveformeditorMenu.Text = 'Waveform editor...';
            app.HelpMenu = uimenu(app.FourierSeriesExplorerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.FourierseriespropertiesexplainedMenu = uimenu(app.HelpMenu);
            app.FourierseriespropertiesexplainedMenu.MenuSelectedFcn = createCallbackFcn(app, @FourierseriespropertiesexplainedMenuSelected, true);
            app.FourierseriespropertiesexplainedMenu.Text = 'Fourier series properties explained';
            app.TipMenu = uimenu(app.HelpMenu);
            app.TipMenu.MenuSelectedFcn = createCallbackFcn(app, @TipMenuSelected, true);
            app.TipMenu.Text = 'Tip...';
            app.ContactthetutorMenu = uimenu(app.HelpMenu);
            app.ContactthetutorMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactthetutorMenuSelected, true);
            app.ContactthetutorMenu.Text = 'Contact the tutor...';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.TabGroup = uitabgroup(app.FourierSeriesExplorerUIFigure);
            app.AnalysisSynthesisTab = uitab(app.TabGroup);
            app.AnalysisSynthesisTab.Title = 'Analysis & Synthesis';
            app.GridLayout = uigridlayout(app.AnalysisSynthesisTab);
            app.ControlPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.ControlPanel);
            app.InputsPanel = uipanel(app.GridLayout2);
            app.ComplexcoefficientsCheckBox = uicheckbox(app.InputsPanel);
            app.ComplexcoefficientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComplexcoefficientsCheckBoxValueChanged, true);
            app.ComplexcoefficientsCheckBox.Text = 'Complex coefficients';
            app.WaveformperiodsSliderLabel = uilabel(app.InputsPanel);
            app.WaveformperiodsSliderLabel.Text = 'Waveform period (s):';
            app.WaveformperiodsSlider = uislider(app.InputsPanel);
            app.WaveformperiodsSlider.Limits = [1 20];
            app.WaveformperiodsSlider.MajorTicks = [1 5 10 15 20];
            app.WaveformperiodsSlider.MajorTickLabels = {'1', '5', '10', '15', '20'};
            app.WaveformperiodsSlider.ValueChangedFcn = createCallbackFcn(app, @WaveformperiodsSliderValueChanged, true);
            app.WaveformperiodsSlider.ValueChangingFcn = createCallbackFcn(app, @WaveformperiodsSliderValueChanging, true);
            app.WaveformperiodsSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.WaveformperiodsSlider.Value = 10;
            app.NumberofharmonicsNSpinnerLabel = uilabel(app.InputsPanel);
            app.NumberofharmonicsNSpinnerLabel.Text = 'Number of harmonics (N):';
            app.NumberofharmonicsSpinner = uispinner(app.InputsPanel);
            app.NumberofharmonicsSpinner.ValueChangingFcn = createCallbackFcn(app, @NumberofharmonicsSpinnerValueChanging, true);
            app.NumberofharmonicsSpinner.Limits = [0 500];
            app.NumberofharmonicsSpinner.Value = 30;
            app.ExamineButtonGroup = uibuttongroup(app.InputsPanel);
            app.ExamineButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExamineButtonGroupSelectionChanged, true);
            app.ExamineButtonGroup.Title = 'Examine:';
            app.PlotCoefficients = uiradiobutton(app.ExamineButtonGroup);
            app.PlotCoefficients.Text = 'Coefficients';
            app.PlotCoefficients.Value = true;
            app.PlotHarmonics = uiradiobutton(app.ExamineButtonGroup);
            app.PlotHarmonics.Text = 'Harmonics';
            app.Panel = uipanel(app.GridLayout2);
            app.AnalysisFormula = uiimage(app.Panel);
            app.AnalysisFormula.ImageSource = 'analysis_real_coeffs.PNG';
            app.SynthesisFormula = uiimage(app.Panel);
            app.SynthesisFormula.ImageSource = 'synthesis_real_coeffs.PNG';
            app.AnalysisLabel = uilabel(app.Panel);
            app.AnalysisLabel.Text = 'Analysis:';
            app.SynthesisLabel = uilabel(app.Panel);
            app.SynthesisLabel.Text = 'Synthesis:';
            app.SynthesisRMSErrorEditFieldLabel = uilabel(app.Panel);
            app.SynthesisRMSErrorEditFieldLabel.Text = 'Synthesis RMS Error:';
            app.SynthesisRMSErrorEditField = uieditfield(app.Panel, 'numeric');
            app.SynthesisRMSErrorEditField.Editable = 'off';
            app.ResultsPanel = uipanel(app.GridLayout);
            app.WaveformAxes = uiaxes(app.ResultsPanel);
            title(app.WaveformAxes, 'Fourier Series Approximation')
            xlabel(app.WaveformAxes, 't (s)')
            ylabel(app.WaveformAxes, 'f(t)')
            app.CoefficientsAxes = uiaxes(app.ResultsPanel);
            title(app.CoefficientsAxes, 'Fourier Series Coefficients')
            xlabel(app.CoefficientsAxes, 'Coefficient Index, n')
            ylabel(app.CoefficientsAxes, 'Amplitude')
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout3 = uigridlayout(app.PropertiesTab);
            app.Panel_2 = uipanel(app.GridLayout3);
            app.PropertyDropDownLabel = uilabel(app.Panel_2);
            app.PropertyDropDownLabel.Text = 'Property:';
            app.PropertyDropDown = uidropdown(app.Panel_2);
            app.PropertyDropDown.Items = {'Linearity', 'Time reversal', 'Time scaling', 'Time shift', 'Convolution in time', 'Convolution in frequency', 'Conjugate & Symmetry'};
            app.PropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyDropDownValueChanged, true);
            app.PropertyDropDown.Value = 'Linearity';
            app.TimeDomainEquation = uiimage(app.Panel_2);
            app.TimeDomainEquation.ImageSource = 'linearity_time.PNG';
            app.CoefficientEquation = uiimage(app.Panel_2);
            app.CoefficientEquation.ImageSource = 'linearity_frequency.PNG';
            app.ExamineButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.ExamineButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ExamineButtonGroup_2SelectionChanged, true);
            app.ExamineButtonGroup_2.Title = 'Examine:';
            app.TimeDomain = uiradiobutton(app.ExamineButtonGroup_2);
            app.TimeDomain.Text = 'Time domain';
            app.TimeDomain.Value = true;
            app.FourierCoefficients = uiradiobutton(app.ExamineButtonGroup_2);
            app.FourierCoefficients.Text = 'Fourier Coefficients';
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b:';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 2;
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.Panel_2, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = '1';
            app.t0EditFieldLabel = uilabel(app.Panel_2);
            app.t0EditFieldLabel.Text = 't0:';
            app.t0EditField = uieditfield(app.Panel_2, 'numeric');
            app.t0EditField.ValueChangedFcn = createCallbackFcn(app, @t0EditFieldValueChanged, true);
            app.t0EditField.Value = 3.5;
            app.FunctionxButtonGroup = uibuttongroup(app.Panel_2);
            app.FunctionxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FunctionxButtonGroupSelectionChanged, true);
            app.FunctionxButtonGroup.Title = 'Function x:';
            app.ComplexvaluedButton = uiradiobutton(app.FunctionxButtonGroup);
            app.ComplexvaluedButton.Text = 'Complex valued';
            app.ComplexvaluedButton.Value = true;
            app.RealvaluedButton = uiradiobutton(app.FunctionxButtonGroup);
            app.RealvaluedButton.Text = 'Real valued';
            app.ThreeFigures = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.ThreeFigures);
            app.xFunAxes = uiaxes(app.GridLayout4);
            title(app.xFunAxes, 'Function x(t)')
            xlabel(app.xFunAxes, 'Time (s)')
            ylabel(app.xFunAxes, 'x(t)')
            app.yFunAxes = uiaxes(app.GridLayout4);
            title(app.yFunAxes, 'Function y(t)')
            xlabel(app.yFunAxes, 'Time (s)')
            ylabel(app.yFunAxes, 'y(t)')
            app.zFunAxes = uiaxes(app.GridLayout4);
            title(app.zFunAxes, 'Function z(t)')
            xlabel(app.zFunAxes, 'Time (s)')
            ylabel(app.zFunAxes, 'z(t)')
            app.TwoFigures = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.TwoFigures);
            app.xFunAxes2 = uiaxes(app.GridLayout5);
            title(app.xFunAxes2, 'Title')
            xlabel(app.xFunAxes2, 'Time (s)')
            ylabel(app.xFunAxes2, 'Amplitude')
            app.zFunAxes2 = uiaxes(app.GridLayout5);
            title(app.zFunAxes2, 'Title')
            xlabel(app.zFunAxes2, 'Fourier Coefficients, n')
            ylabel(app.zFunAxes2, 'Amplitude')
            % Show the figure after all components are created
            app.FourierSeriesExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeriesExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierSeriesExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierSeriesExplorerUIFigure)
        end
    end
end