classdef ImportWaveformFSE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportWaveformFourierSeriesExplorerUIFigure  matlab.ui.Figure
        Label                    matlab.ui.control.Label
        WaveformvariableLabel    matlab.ui.control.Label
        WaveformvariableListBox  matlab.ui.control.ListBox
        PeriodsEditFieldLabel    matlab.ui.control.Label
        PeriodsEditField         matlab.ui.control.NumericEditField
        RefreshButton            matlab.ui.control.Button
        ImportButton             matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Parent % Parent App handle
    end
    
    methods (Access = private)
        function updateVariables(app)
            vars = evalin('base', 'whos');
            cnt = 0;
            for p=1:length(vars)
                if length(vars(p).size) == 2 && any(vars(p).size == 1) ...
                        && ~all(vars(p).size == 1) && ...
                        (strcmp(vars(p).class, 'double') || strcmp(vars(p).class, 'single'))
                    cnt = cnt + 1;
                    app.WaveformvariableListBox.Items{cnt} = vars(p).name;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.Parent = parent;
            updateVariables(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            updateVariables(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.ImportWaveformFourierSeriesExplorerUIFigure)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            amplitudeValues = evalin('base',app.WaveformvariableListBox.Value);
            importWaveform(app.Parent, ...
                amplitudeValues, ...
                app.PeriodsEditField.Value)
            delete(app.ImportWaveformFourierSeriesExplorerUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportWaveformFourierSeriesExplorerUIFigure = uifigure('Visible', 'off');
            app.ImportWaveformFourierSeriesExplorerUIFigure.Name = 'Import Waveform - Fourier Series Explorer';
            app.Label = uilabel(app.ImportWaveformFourierSeriesExplorerUIFigure);
            app.Label.Text = {'Select the variable in the workspace that holds the samples of '; 'the waveform for one period. The waveform must be a vector '; 'of floating-point numbers. Also, enter the fundemental period of '; 'the waveform in seconds.'};
            app.WaveformvariableLabel = uilabel(app.ImportWaveformFourierSeriesExplorerUIFigure);
            app.WaveformvariableLabel.Text = {'Waveform '; 'variable:'};
            app.WaveformvariableListBox = uilistbox(app.ImportWaveformFourierSeriesExplorerUIFigure);
            app.WaveformvariableListBox.Items = {};
            app.WaveformvariableListBox.Value = {};
            app.PeriodsEditFieldLabel = uilabel(app.ImportWaveformFourierSeriesExplorerUIFigure);
            app.PeriodsEditFieldLabel.Text = 'Period (s):';
            app.PeriodsEditField = uieditfield(app.ImportWaveformFourierSeriesExplorerUIFigure, 'numeric');
            app.PeriodsEditField.LowerLimitInclusive = 'off';
            app.PeriodsEditField.Limits = [0 Inf];
            app.PeriodsEditField.Value = 1;
            app.RefreshButton = uibutton(app.ImportWaveformFourierSeriesExplorerUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ImportButton = uibutton(app.ImportWaveformFourierSeriesExplorerUIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CancelButton = uibutton(app.ImportWaveformFourierSeriesExplorerUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ImportWaveformFourierSeriesExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportWaveformFSE(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportWaveformFourierSeriesExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportWaveformFourierSeriesExplorerUIFigure)
        end
    end
end